classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UploadImageButton   matlab.ui.control.Button
        CompareSizesButton  matlab.ui.control.Button
        RLEButton           matlab.ui.control.Button
        DCTButton           matlab.ui.control.Button
        HuffmanButton       matlab.ui.control.Button
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function upladimageButtonPushed(app, event)

        end

        % Button pushed function: HuffmanButton
        function HuffmanButtonPushed(app, event)

            if isempty(app.UIAxes.UserData), return; end 
            img = rgb2gray(app.UIAxes.UserData);
            symbols = double(unique(img));
            counts = histcounts(img, [symbols; max(symbols)+1]);
            p = counts ./ sum(counts);
            dict = huffmandict(symbols, p);
            comp = huffmanenco(img(:), dict);
            decomp = huffmandeco(comp, dict);
            imshow (app.UIAxes2.UserDatata);
            imshow(app.UIAxes2.UserData, 'Parent', app.UIAxes2);
        end = reshape(uint8(decomp), size(app.UserData);
            imshow(app.UIAxes2.UserData, 'Parent', app.UIAxes2);
        end

        % Button pushed function: DCTButton
        function DCTButtonPushed(app, event)
           
            if isempty(app.UIAxes.UserData), return; end
            I = rgb2gray(app.UIAxes.UserData);
            I = im2double(I);
            T = dctmtx(8);
            dct_func = @(block_struct) T * block_struct.data * T';
            B = blockproc(I,[8 8],dct_func);)
            mask = [1 1 1 1 0 0 0 0;
                1 1 1 0 0 0 0 0;
                1 1 0 0 0 0 0 0;
                1 0 0 0 0 0 0 0;
                0 0 0 0 0 0 0 0;
                0 0 0 0 0 0 0 0;
                0 0 0 0 0 0 0 0;
                0 0 0 0 0 0 0 0];
            B2 = blockproc(B,[8 8],@(block_struct) mask .* block_struct.data);

            invdct = @(block_struct) T' * block_struct.data * T;
            result = blockproc(B2,[8 8],invdct);
            app.UIAxes2.UserData = im2uint8(result);
            imshow(app.UIAxes2.UserData, 'Parent', app.UIAxes2);
        end

        % Button pushed function: RLEButton
        function RLEButtonPushed(app, event)
            
            if isempty(app.UIAxes.UserData), return; end
            img = rgb2gray(app.UIAxes.UserData);
            flat_img = img(:)';  
            n = length(flat_img);
            i = [find(flat_img(1:end-1) ~= flat_img(2:end)) n];
            counts = diff([0 i]);
            values = flat_img(i);
            decompressed = repelem(values, counts);
            result = reshape(uint8(decompressed), size(img));
            app.UIAxes2.UserData = result;
            imshow(app.UIAxes2.UserData, 'Parent', app.UIAxes2);
        end

        % Button pushed function: CompareSizesButton
        function CompareSizesButtonPushed(app, event)
            if isempty(app.UIAxes2.UserData), return; end
            imwrite(app.UIAxes.UserData, 'temp_orig.bmp');
            imwrite(app.UIAxes2.UserData, 'temp_comp.jpg', 'Quality', 30); % المضغوطة

            s1 = dir('temp_orig.bmp');
            s2 = dir('temp_comp.jpg');
            saving = (1 - (s2.bytes / s1.bytes)) * 100;
            app.Label.Text = sprintf('Orig: %.1fKB | Comp: %.1fKB | Saved: %.1f%%', ...
                s1.bytes/1024, s2.bytes/1024, saving);
        end

        % Callback function
        function UploadImageButtonPushed(app, event)

            [file, path] = uigetfile({'*.jpg;*.png;*.bmp'});
            if isequal(file,0), return; end
            img = imread(fullfile(path, file));
            imshow(img, 'Parent', app.UIAxes);
            app.UIAxes.UserData = img;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'original image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'compressed image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HuffmanButton = uibutton(app.UIFigure, 'push');
            app.HuffmanButton.ButtonPushedFcn = createCallbackFcn(app, @HuffmanButtonPushed, true);
            app.HuffmanButton.Text = 'Huffman';
            app.DCTButton = uibutton(app.UIFigure, 'push');
            app.DCTButton.ButtonPushedFcn = createCallbackFcn(app, @DCTButtonPushed, true);
            app.DCTButton.Text = 'DCT';
            app.RLEButton = uibutton(app.UIFigure, 'push');
            app.RLEButton.ButtonPushedFcn = createCallbackFcn(app, @RLEButtonPushed, true);
            app.RLEButton.Text = 'RLE';
            app.CompareSizesButton = uibutton(app.UIFigure, 'push');
            app.CompareSizesButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSizesButtonPushed, true);
            app.CompareSizesButton.Text = 'Compare Sizes';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.Text = 'Upload Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end