classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label              matlab.ui.control.Label
        CompareSizeButton  matlab.ui.control.Button
        RLEButton          matlab.ui.control.Button
        DCTButton          matlab.ui.control.Button
        HuffmanButton      matlab.ui.control.Button
        UploadImageButton  matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)

                [file, path] = uigetfile({'*.jpg;*.png;*.bmp'});
                if isequal(file,0), return; end
                img = imread(fullfile(path, file));
                imshow(img, 'Parent', app.UIAxes);
                app.UIAxes.UserData = img; 
            
        end

        % Button pushed function: HuffmanButton
        function HuffmanButtonPushed(app, event)

                if isempty(app.UIAxes.UserData), return; end
                img = rgb2gray(app.UIAxes.UserData);
                symbols = double(unique(img));
                counts = histcounts(img, [symbols; max(symbols)+1]);
                p = counts ./ sum(counts);
                dict = huffmandict(symbols, p);
                comp = huffmanenco(img(:), dict);
                decomp = huffmandeco(comp, dict);
                result = reshape(uint8(decomp), size(app.UIAxes.UserData, 1), size(app.UIAxes.UserData, 2));
                app.UIAxes2.UserData = result;
                imshow(result, 'Parent', app.UIAxes2);
            
        end

        % Button pushed function: DCTButton
        function DCTButtonPushed(app, event)

                if isempty(app.UIAxes.UserData), return; end
                I = rgb2gray(app.UIAxes.UserData);
                I = im2double(I);
                T = dctmtx(8);
                dct_func = @(block_struct) T * block_struct.data * T';
                B = blockproc(I, [8 8], dct_func);
                mask = [1 1 1 1 0 0 0 0; 1 1 1 0 0 0 0 0; 1 1 0 0 0 0 0 0; 1 0 0 0 0 0 0 0;
                    0 0 0 0 0 0 0 0; 0 0 0 0 0 0 0 0; 0 0 0 0 0 0 0 0; 0 0 0 0 0 0 0 0];
                B2 = blockproc(B, [8 8], @(block_struct) mask .* block_struct.data);
                inv_dct = @(block_struct) T' * block_struct.data * T;
                result = blockproc(B2, [8 8], inv_dct);
                app.UIAxes2.UserData = result;
                imshow(result, 'Parent', app.UIAxes2);
            
        end

        % Button pushed function: RLEButton
        function RLEButtonPushed(app, event)

                if isempty(app.UIAxes.UserData), return; end
                img = rgb2gray(app.UIAxes.UserData);
                flat_img = img(:)';
                n = length(flat_img);
                i = [find(flat_img(1:end-1) ~= flat_img(2:end)) n];
                counts = diff([0 i]);
                values = flat_img(i);
                decompressed = repelem(values, counts);
                result = reshape(uint8(decompressed), size(app.UIAxes.UserData));
                app.UIAxes2.UserData = result;
                imshow(result, 'Parent', app.UIAxes2);
            
        end

        % Button pushed function: CompareSizeButton
        function CompareSizeButtonPushed(app, event)

                if isempty(app.UIAxes2.UserData), return; end
                imwrite(app.UIAxes.UserData, 'temp_orig.bmp');
                imwrite(app.UIAxes2.UserData, 'temp_comp.jpg', 'Quality', 30);
                s1 = dir('temp_orig.bmp');
                s2 = dir('temp_comp.jpg');
                saving = (1 - (s2.bytes / s1.bytes)) * 100;
                app.Label.Text = sprintf('Orig: %.1f KB | Comp: %.1f KB | Saved: %.1f%%', ...
                    s1.bytes/1024, s2.bytes/1024, saving);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Compressed Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.HuffmanButton = uibutton(app.UIFigure, 'push');
            app.HuffmanButton.ButtonPushedFcn = createCallbackFcn(app, @HuffmanButtonPushed, true);
            app.HuffmanButton.Text = 'Huffman';
            app.DCTButton = uibutton(app.UIFigure, 'push');
            app.DCTButton.ButtonPushedFcn = createCallbackFcn(app, @DCTButtonPushed, true);
            app.DCTButton.Text = 'DCT';
            app.RLEButton = uibutton(app.UIFigure, 'push');
            app.RLEButton.ButtonPushedFcn = createCallbackFcn(app, @RLEButtonPushed, true);
            app.RLEButton.Text = 'RLE';
            app.CompareSizeButton = uibutton(app.UIFigure, 'push');
            app.CompareSizeButton.ButtonPushedFcn = createCallbackFcn(app, @CompareSizeButtonPushed, true);
            app.CompareSizeButton.Text = 'Compare Size';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end